classdef loginanotaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginAnotacionesUIFigure     matlab.ui.Figure
        ANOTACIONESAUTOMTICASLabel   matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        AnotacionesAutomticasButton  matlab.ui.control.Button
        CrearalgoritmoButton         matlab.ui.control.StateButton
        ContraseaEditField           matlab.ui.control.EditField
        ContraseaEditFieldLabel      matlab.ui.control.Label
        UsuarioEditField             matlab.ui.control.EditField
        UsuarioEditFieldLabel        matlab.ui.control.Label
        IniciarSesinButton           matlab.ui.control.StateButton
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp
        usuario = 0
        pass = 0
        MainAlgoapp
        MainGrafiapp
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        MLTesteo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            %app.Label.Text = num2str(app.CallingApp.MLTesteo);
            app.AnotacionesAutomticasButton.Enable = 'off';
            app.CrearalgoritmoButton.Enable = 'off';
        end

        % Value changed function: IniciarSesinButton
        function IniciarSesinButtonValueChanged(app, event)
          if ((app.UsuarioEditField.Value == "Doctor")&&(app.ContraseaEditField.Value == "1234"))
              app.usuario = 1;
              app.AnotacionesAutomticasButton.Enable = 'on';
              app.CrearalgoritmoButton.Enable = 'off';
         % end  
          elseif ((app.UsuarioEditField.Value == "Admin")&&(app.ContraseaEditField.Value == "1234"))
              app.usuario = 1;
              app.AnotacionesAutomticasButton.Enable = 'on';
              app.CrearalgoritmoButton.Enable = 'on';
          else 
              app.usuario = 0;
              app.AnotacionesAutomticasButton.Enable = 'off';
              app.CrearalgoritmoButton.Enable = 'off';
          end
          
        end

        % Value changed function: CrearalgoritmoButton
        function CrearalgoritmoButtonValueChanged(app, event)
            app.MainAlgoapp = generacionalgoritmo(app);
        end

        % Button pushed function: AnotacionesAutomticasButton
        function AnotacionesAutomticasButtonPushed(app, event)
            app.MainGrafiapp = inicioanotacionesauto(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginAnotacionesUIFigure = uifigure('Visible', 'off');
            app.LoginAnotacionesUIFigure.Name = 'Login Anotaciones';
            app.Image = uiimage(app.LoginAnotacionesUIFigure);
            app.Image.ImageSource = 'logouvg.jpg';
            app.IniciarSesinButton = uibutton(app.LoginAnotacionesUIFigure, 'state');
            app.IniciarSesinButton.ValueChangedFcn = createCallbackFcn(app, @IniciarSesinButtonValueChanged, true);
            app.IniciarSesinButton.Text = 'Iniciar Sesión';
            app.UsuarioEditFieldLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.LoginAnotacionesUIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.LoginAnotacionesUIFigure, 'text');
            app.CrearalgoritmoButton = uibutton(app.LoginAnotacionesUIFigure, 'state');
            app.CrearalgoritmoButton.ValueChangedFcn = createCallbackFcn(app, @CrearalgoritmoButtonValueChanged, true);
            app.CrearalgoritmoButton.Text = 'Crear algoritmo';
            app.AnotacionesAutomticasButton = uibutton(app.LoginAnotacionesUIFigure, 'push');
            app.AnotacionesAutomticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesAutomticasButtonPushed, true);
            app.AnotacionesAutomticasButton.Text = 'Anotaciones Automáticas';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.ANOTACIONESAUTOMTICASLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.ANOTACIONESAUTOMTICASLabel.Text = 'ANOTACIONES AUTOMÁTICAS';
            % Show the figure after all components are created
            app.LoginAnotacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loginanotaciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginAnotacionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginAnotacionesUIFigure)
        end
    end
end